classdef datstat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EMDataManagerUIFigure           matlab.ui.Figure
        read_data                       matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        loaded_data                     matlab.ui.control.ListBox
        remove_data                     matlab.ui.control.Button
        select                          matlab.ui.control.Button
        FilterNowButton                 matlab.ui.control.Button
        put_in_base                     matlab.ui.control.Button
        NameEditFieldLabel              matlab.ui.control.Label
        file_name                       matlab.ui.control.EditField
        rh                              matlab.ui.control.CheckBox
        rv                              matlab.ui.control.CheckBox
        rt                              matlab.ui.control.CheckBox
        ChansLabel                      matlab.ui.control.Label
        OrderEditFieldLabel             matlab.ui.control.Label
        filt_order                      matlab.ui.control.NumericEditField
        CutoffEditFieldLabel            matlab.ui.control.Label
        filt_cutoff                     matlab.ui.control.NumericEditField
        lh                              matlab.ui.control.CheckBox
        lv                              matlab.ui.control.CheckBox
        lt                              matlab.ui.control.CheckBox
        SinglefileonlyoperationsLabel   matlab.ui.control.Label
        SinglemultifileoperationsLabel  matlab.ui.control.Label
        auto_filt                       matlab.ui.control.CheckBox
        calc_vel                        matlab.ui.control.CheckBox
        HzLabel                         matlab.ui.control.Label
        PathTextAreaLabel               matlab.ui.control.Label
        file_path                       matlab.ui.control.TextArea
        sampsEditFieldLabel_2           matlab.ui.control.Label
        num_samps                       matlab.ui.control.NumericEditField
        FreqEditFieldLabel              matlab.ui.control.Label
        samp_freq                       matlab.ui.control.NumericEditField
        CloseButton                     matlab.ui.control.Button
        AtreadLabel                     matlab.ui.control.Label
        applybias                       matlab.ui.control.CheckBox
        LPFilterDropDownLabel           matlab.ui.control.Label
        LPFilter                        matlab.ui.control.DropDown
    end

   
   properties (Access = public)
   end
   
   methods (Access = public)
   end
   
   methods (Access = private)
      
      function update_datalist(app)
         app.loaded_data.Items={};
         temp=evalin('base','whos');
         cnt=0;
         tlen=length(temp);
         a=cell(tlen,1);
         for i=1:tlen
            if strcmpi(temp(i).class,'emData')
               cnt=cnt+1;
               a{cnt}=temp(i).name;
               app.loaded_data.Items{cnt}=a{cnt};
            end
         end
      end
      
      function saveprefs(app)
         olddir=pwd;
         wpos=app.EMDataManagerUIFigure.Position;
         wpos=wpos(1:2);
         cd(findomprefs)
         save('datamgr.mat', 'wpos')
         cd(olddir)
      end
      
   end
   

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         
         set(0,'ShowHiddenHandles','on')
         ch = get(0,'Children');
         keeper=0;
         for i=1:length(ch)
            if strcmpi(ch(i).Name, 'EM Data Manager') ...
                  && strcmpi(ch(i).Tag,'set')
               keeper=i;
            end
         end
         if keeper>=1
            ML_W_switch('mlw')
            figure(ch(keeper))
            delete(app)
            return
         end
         
         olddir=pwd;
         cd(findomprefs)
         scrsize=get(0,'ScreenSize');
         if exist('datamgr.mat','file')
            load datamgr.mat
         else
            wpos = [scrsize(3)/2 scrsize(4)/2];
         end
         cd(olddir)
         cursize=app.EMDataManagerUIFigure.Position;
         newsize = [wpos(1:2) cursize(3) cursize(4)];
         app.EMDataManagerUIFigure.Position = newsize;
         
         finfo=struct();
         app.loaded_data.UserData = finfo;
         app.EMDataManagerUIFigure.Tag = 'set';
         app.EMDataManagerUIFigure.UserData = app;
         update_datalist(app);
         
        end

        % Button pushed function: read_data
        function add_data(app, event)
         ML_W_switch('ml')
         update_datalist(app);
         out=rd;
         if isempty(out)
            return
         end
         WaitSecs(1);
         if ~isempty(out)
            loaded=app.loaded_data.Items;
            nextfile=length(loaded)+1;
            app.loaded_data.Items{nextfile}=strtok(out.filename,'.');
            sname=lower(strtok(out.filename,'.'));
            
            finfo =app.loaded_data.UserData;
            finfo(nextfile).filename=out.filename;
            finfo(nextfile).pathname=out.pathname;
            finfo(nextfile).samp_freq=out.samp_freq;
            finfo(nextfile).numsamps=out.numsamps;
            app.loaded_data.UserData=finfo;
            app.file_name.Value = out.filename;
            app.file_path.Value = out.pathname;
            app.num_samps.Value = out.numsamps;
            app.samp_freq.Value = out.samp_freq;
            
            assignin('base', sname, out)
            update_datalist(app)
            disp(['Data added to the base workspace as the emData structure variable ' sname])
            dname = strtok(out.filename,'.');
            emd_extract(dname);
            saveprefs(app)
         end
        end

        % Button pushed function: remove_data
        function delete_data(app, event)
         selected = app.loaded_data.Value;
         try
            evalin('base',['clear ' selected])
         catch
         end
         app.file_name.Value = '';
         app.file_path.Value = '';
         app.num_samps.Value = 0;
         app.samp_freq.Value = 0;
         
         update_datalist(app);
        end

        % Value changed function: loaded_data
        function update_finfo(app, event)
         value = app.loaded_data.Value;
         %          finfo = app.loaded_data.UserData;
         %          if ~isempty(fieldnames(finfo))
         %             for i=1:length(finfo)
         %                if strcmpi(value,strtok(finfo(i).filename,'.'))
         %                   EMD=finfo(i);
         %                   break
         %                end
         %             end
         %          else
         EMD=getEMD(value);
         %          end
         app.file_name.Value = EMD.filename;
         app.file_path.Value = EMD.pathname;
         app.num_samps.Value = EMD.numsamps;
         app.samp_freq.Value = EMD.samp_freq;
         saveprefs(app)
        end

        % Callback function: CloseButton, EMDataManagerUIFigure
        function closefcn(app, event)
         saveprefs(app)
         delete(app.EMDataManagerUIFigure)
        end

        % Button pushed function: put_in_base
        function put_data_in_base(app, event)
         value=app.loaded_data.Value;
         emd_extract(value);
        end

        % Button pushed function: FilterNowButton
        function filt_now(app, event)
         
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EMDataManagerUIFigure = uifigure;
            app.EMDataManagerUIFigure.Name = 'EM Data Manager';
            app.EMDataManagerUIFigure.CloseRequestFcn = createCallbackFcn(app, @closefcn, true);
            app.read_data = uibutton(app.EMDataManagerUIFigure, 'push');
            app.read_data.ButtonPushedFcn = createCallbackFcn(app, @add_data, true);
            app.read_data.Text = 'Read';
            app.Label = uilabel(app.EMDataManagerUIFigure);
            app.Label.Text = '';
            app.loaded_data = uilistbox(app.EMDataManagerUIFigure);
            app.loaded_data.Items = {};
            app.loaded_data.ValueChangedFcn = createCallbackFcn(app, @update_finfo, true);
            app.loaded_data.Value = {};
            app.remove_data = uibutton(app.EMDataManagerUIFigure, 'push');
            app.remove_data.ButtonPushedFcn = createCallbackFcn(app, @delete_data, true);
            app.remove_data.Text = 'Remove';
            app.select = uibutton(app.EMDataManagerUIFigure, 'push');
            app.select.Text = 'Select';
            app.FilterNowButton = uibutton(app.EMDataManagerUIFigure, 'push');
            app.FilterNowButton.ButtonPushedFcn = createCallbackFcn(app, @filt_now, true);
            app.FilterNowButton.Text = 'Filter Now';
            app.put_in_base = uibutton(app.EMDataManagerUIFigure, 'push');
            app.put_in_base.ButtonPushedFcn = createCallbackFcn(app, @put_data_in_base, true);
            app.put_in_base.Text = 'Copy to base';
            app.NameEditFieldLabel = uilabel(app.EMDataManagerUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.file_name = uieditfield(app.EMDataManagerUIFigure, 'text');
            app.file_name.Editable = 'off';
            app.rh = uicheckbox(app.EMDataManagerUIFigure);
            app.rh.Text = 'rh';
            app.rh.Value = true;
            app.rv = uicheckbox(app.EMDataManagerUIFigure);
            app.rv.Text = 'rv';
            app.rv.Value = true;
            app.rt = uicheckbox(app.EMDataManagerUIFigure);
            app.rt.Text = 'rt';
            app.ChansLabel = uilabel(app.EMDataManagerUIFigure);
            app.ChansLabel.Text = 'Chans:';
            app.OrderEditFieldLabel = uilabel(app.EMDataManagerUIFigure);
            app.OrderEditFieldLabel.Text = 'Order';
            app.filt_order = uieditfield(app.EMDataManagerUIFigure, 'numeric');
            app.filt_order.ValueDisplayFormat = '%.0f';
            app.filt_order.Value = 4;
            app.CutoffEditFieldLabel = uilabel(app.EMDataManagerUIFigure);
            app.CutoffEditFieldLabel.Text = 'Cutoff';
            app.filt_cutoff = uieditfield(app.EMDataManagerUIFigure, 'numeric');
            app.lh = uicheckbox(app.EMDataManagerUIFigure);
            app.lh.Text = 'lh';
            app.lh.Value = true;
            app.lv = uicheckbox(app.EMDataManagerUIFigure);
            app.lv.Text = 'lv';
            app.lv.Value = true;
            app.lt = uicheckbox(app.EMDataManagerUIFigure);
            app.lt.Text = 'lt';
            app.SinglefileonlyoperationsLabel = uilabel(app.EMDataManagerUIFigure);
            app.SinglefileonlyoperationsLabel.Text = 'Single-file-only operations';
            app.SinglemultifileoperationsLabel = uilabel(app.EMDataManagerUIFigure);
            app.SinglemultifileoperationsLabel.Text = 'Single/multi-file operations';
            app.auto_filt = uicheckbox(app.EMDataManagerUIFigure);
            app.auto_filt.Text = 'Filter';
            app.auto_filt.Value = true;
            app.calc_vel = uicheckbox(app.EMDataManagerUIFigure);
            app.calc_vel.Text = 'Calc. vel.';
            app.calc_vel.Value = true;
            app.HzLabel = uilabel(app.EMDataManagerUIFigure);
            app.HzLabel.Text = 'Hz';
            app.PathTextAreaLabel = uilabel(app.EMDataManagerUIFigure);
            app.PathTextAreaLabel.Text = 'Path';
            app.file_path = uitextarea(app.EMDataManagerUIFigure);
            app.file_path.Editable = 'off';
            app.sampsEditFieldLabel_2 = uilabel(app.EMDataManagerUIFigure);
            app.sampsEditFieldLabel_2.Text = '#samps';
            app.num_samps = uieditfield(app.EMDataManagerUIFigure, 'numeric');
            app.num_samps.ValueDisplayFormat = '%.0f';
            app.num_samps.Editable = 'off';
            app.FreqEditFieldLabel = uilabel(app.EMDataManagerUIFigure);
            app.FreqEditFieldLabel.Text = 'Freq';
            app.samp_freq = uieditfield(app.EMDataManagerUIFigure, 'numeric');
            app.samp_freq.Editable = 'off';
            app.CloseButton = uibutton(app.EMDataManagerUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @closefcn, true);
            app.CloseButton.Text = 'Close';
            app.AtreadLabel = uilabel(app.EMDataManagerUIFigure);
            app.AtreadLabel.Text = 'At read:';
            app.applybias = uicheckbox(app.EMDataManagerUIFigure);
            app.applybias.Text = 'Apply bias';
            app.applybias.Value = true;
            app.LPFilterDropDownLabel = uilabel(app.EMDataManagerUIFigure);
            app.LPFilterDropDownLabel.Text = 'LP Filter:';
            app.LPFilter = uidropdown(app.EMDataManagerUIFigure);
            app.LPFilter.Items = {'Butterworth'};
            app.LPFilter.Value = 'Butterworth';
        end
    end

    methods (Access = public)

        % Construct app
        function app = datstat

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EMDataManagerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EMDataManagerUIFigure)
        end
    end
end